classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GoodVibrationsLabel            matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        MetronomeTab                   matlab.ui.container.Tab
        MetronomeSwitchLabel           matlab.ui.control.Label
        MetronomeSwitch                matlab.ui.control.Switch
        VibrationIntensityKnob_2Label  matlab.ui.control.Label
        VibrationIntensityKnob_2       matlab.ui.control.Knob
        BPMEditFieldLabel              matlab.ui.control.Label
        BPMEditField                   matlab.ui.control.NumericEditField
        NumeratorEditFieldLabel        matlab.ui.control.Label
        NumeratorEditField             matlab.ui.control.NumericEditField
        DenominatorEditFieldLabel      matlab.ui.control.Label
        DenominatorEditField           matlab.ui.control.NumericEditField
        TimeSignatureLabel             matlab.ui.control.Label
        PitchCorrectionTab             matlab.ui.container.Tab
        ScaleTypeButtonGroup           matlab.ui.container.ButtonGroup
        MajorButton                    matlab.ui.control.ToggleButton
        MinorButton                    matlab.ui.control.ToggleButton
        ScaleButtonGroup               matlab.ui.container.ButtonGroup
        AButton                        matlab.ui.control.ToggleButton
        BButton                        matlab.ui.control.ToggleButton
        CButton                        matlab.ui.control.ToggleButton
        DButton                        matlab.ui.control.ToggleButton
        EButton                        matlab.ui.control.ToggleButton
        MetronomeAudioOnlySwitchLabel  matlab.ui.control.Label
        MetronomeAudioOnlySwitch       matlab.ui.control.Switch
        VibrationIntensityKnobLabel    matlab.ui.control.Label
        VibrationIntensityKnob         matlab.ui.control.Knob
        FButton                        matlab.ui.control.ToggleButton
        GButton                        matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        % in metronome section
        bpm % beats per minute
        timeSigNum % how many beats in a measure
        timeSigDen % which note is worth 1 beat
        metVibToggle % toggle metronome on or off
        metVibIntensity % gain for vibration intensity
        
        % in pitch correction section
        pitchVibIntesity % gain for vibration intensity
        pitchVibToggle % toggle metronome on or off (audio only)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BPMEditField
        function BPMEditFieldValueChanged(app, event)
            app.bpm = app.BPMEditField.Value;
        end

        % Value changed function: NumeratorEditField
        function NumeratorEditFieldValueChanged(app, event)
            app.timeSigNum = app.NumeratorEditField.Value;
        end

        % Value changed function: DenominatorEditField
        function DenominatorEditFieldValueChanged(app, event)
            app.timeSigDen = app.DenominatorEditField.Value;
        end

        % Value changed function: MetronomeSwitch
        function MetronomeSwitchValueChanged(app, event)
            app.metVibToggle = app.MetronomeSwitch.Value;
        end

        % Value changed function: VibrationIntensityKnob_2
        function VibrationIntensityKnob_2ValueChanged(app, event)
            app.metVibIntensity = app.VibrationIntensityKnob_2.Value;
            
        end

        % Selection changed function: ScaleButtonGroup
        function ScaleButtonGroupSelectionChanged(app, event)
            % TO DO
            selectedButton = app.ScaleButtonGroup.SelectedObject;
            if (selectedButton == 'A')
            elseif (selectedButton == 'B')
            elseif (selectedButton == 'C')
            elseif (selectedButton == 'D')
            elseif (selectedButton == 'E')
            elseif (selectedButton == 'F')
            elseif (selectedButton == 'G')
            end
        end

        % Selection changed function: ScaleTypeButtonGroup
        function ScaleTypeButtonGroupSelectionChanged(app, event)
            % TO DO
            selectedButton = app.ScaleTypeButtonGroup.SelectedObject;
            if (selectedButton == "Major")
            elseif (selectedButton == "Minor")
            end
        end

        % Value changed function: VibrationIntensityKnob
        function VibrationIntensityKnobValueChanged(app, event)
            app.pitchVibIntesity = app.VibrationIntensityKnob.Value;
            
        end

        % Value changed function: MetronomeAudioOnlySwitch
        function MetronomeAudioOnlySwitchValueChanged(app, event)
            app.pitchVibToggle = app.MetronomeAudioOnlySwitch.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GoodVibrationsLabel = uilabel(app.UIFigure);
            app.GoodVibrationsLabel.Text = 'Good Vibrations';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MetronomeTab = uitab(app.TabGroup);
            app.MetronomeTab.Title = 'Metronome';
            app.MetronomeSwitchLabel = uilabel(app.MetronomeTab);
            app.MetronomeSwitchLabel.Text = 'Metronome';
            app.MetronomeSwitch = uiswitch(app.MetronomeTab, 'slider');
            app.MetronomeSwitch.ValueChangedFcn = createCallbackFcn(app, @MetronomeSwitchValueChanged, true);
            app.VibrationIntensityKnob_2Label = uilabel(app.MetronomeTab);
            app.VibrationIntensityKnob_2Label.Text = 'Vibration Intensity';
            app.VibrationIntensityKnob_2 = uiknob(app.MetronomeTab, 'continuous');
            app.VibrationIntensityKnob_2.ValueChangedFcn = createCallbackFcn(app, @VibrationIntensityKnob_2ValueChanged, true);
            app.BPMEditFieldLabel = uilabel(app.MetronomeTab);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.MetronomeTab, 'numeric');
            app.BPMEditField.ValueChangedFcn = createCallbackFcn(app, @BPMEditFieldValueChanged, true);
            app.TimeSignatureLabel = uilabel(app.MetronomeTab);
            app.TimeSignatureLabel.Text = 'Time Signature';
            app.NumeratorEditFieldLabel = uilabel(app.MetronomeTab);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.MetronomeTab, 'numeric');
            app.NumeratorEditField.ValueChangedFcn = createCallbackFcn(app, @NumeratorEditFieldValueChanged, true);
            app.DenominatorEditFieldLabel = uilabel(app.MetronomeTab);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.MetronomeTab, 'numeric');
            app.DenominatorEditField.ValueChangedFcn = createCallbackFcn(app, @DenominatorEditFieldValueChanged, true);
            app.PitchCorrectionTab = uitab(app.TabGroup);
            app.PitchCorrectionTab.Title = 'Pitch Correction';
            app.ScaleTypeButtonGroup = uibuttongroup(app.PitchCorrectionTab);
            app.ScaleTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScaleTypeButtonGroupSelectionChanged, true);
            app.ScaleTypeButtonGroup.Title = 'Scale Type';
            app.MajorButton = uitogglebutton(app.ScaleTypeButtonGroup);
            app.MajorButton.Text = 'Major';
            app.MajorButton.Value = true;
            app.MinorButton = uitogglebutton(app.ScaleTypeButtonGroup);
            app.MinorButton.Text = 'Minor';
            app.ScaleButtonGroup = uibuttongroup(app.PitchCorrectionTab);
            app.ScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScaleButtonGroupSelectionChanged, true);
            app.ScaleButtonGroup.Title = 'Scale';
            app.AButton = uitogglebutton(app.ScaleButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uitogglebutton(app.ScaleButtonGroup);
            app.BButton.Text = 'B';
            app.CButton = uitogglebutton(app.ScaleButtonGroup);
            app.CButton.Text = 'C';
            app.DButton = uitogglebutton(app.ScaleButtonGroup);
            app.DButton.Text = {'D'; ''};
            app.EButton = uitogglebutton(app.ScaleButtonGroup);
            app.EButton.Text = 'E';
            app.FButton = uitogglebutton(app.ScaleButtonGroup);
            app.FButton.Text = 'F';
            app.GButton = uitogglebutton(app.ScaleButtonGroup);
            app.GButton.Text = 'G';
            app.MetronomeAudioOnlySwitchLabel = uilabel(app.PitchCorrectionTab);
            app.MetronomeAudioOnlySwitchLabel.Text = 'Metronome (Audio Only)';
            app.MetronomeAudioOnlySwitch = uiswitch(app.PitchCorrectionTab, 'slider');
            app.MetronomeAudioOnlySwitch.ValueChangedFcn = createCallbackFcn(app, @MetronomeAudioOnlySwitchValueChanged, true);
            app.VibrationIntensityKnobLabel = uilabel(app.PitchCorrectionTab);
            app.VibrationIntensityKnobLabel.Text = 'Vibration Intensity';
            app.VibrationIntensityKnob = uiknob(app.PitchCorrectionTab, 'continuous');
            app.VibrationIntensityKnob.ValueChangedFcn = createCallbackFcn(app, @VibrationIntensityKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end